classdef GROUND < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        t3EditField                    matlab.ui.control.NumericEditField
        t2Label                        matlab.ui.control.Label
        t2EditField                    matlab.ui.control.NumericEditField
        wt2Label                       matlab.ui.control.Label
        t1EditField                    matlab.ui.control.NumericEditField
        wLabel                         matlab.ui.control.Label
        CurrentTimeEditField           matlab.ui.control.NumericEditField
        CurrentTimeEditFieldLabel      matlab.ui.control.Label
        CK22TextArea                   matlab.ui.control.TextArea
        CK22TextAreaLabel              matlab.ui.control.Label
        CK21TextArea                   matlab.ui.control.TextArea
        CK21TextAreaLabel              matlab.ui.control.Label
        CK12TextArea                   matlab.ui.control.TextArea
        CK12TextAreaLabel              matlab.ui.control.Label
        CK11TextArea                   matlab.ui.control.TextArea
        CK11TextAreaLabel              matlab.ui.control.Label
        s3EditField                    matlab.ui.control.NumericEditField
        s3EditFieldLabel               matlab.ui.control.Label
        s2EditField                    matlab.ui.control.NumericEditField
        s2EditFieldLabel               matlab.ui.control.Label
        s1EditField                    matlab.ui.control.NumericEditField
        s1EditFieldLabel               matlab.ui.control.Label
        g3EditField                    matlab.ui.control.NumericEditField
        g3EditFieldLabel               matlab.ui.control.Label
        g2EditField                    matlab.ui.control.NumericEditField
        g2EditFieldLabel               matlab.ui.control.Label
        g1EditField                    matlab.ui.control.NumericEditField
        g1EditFieldLabel               matlab.ui.control.Label
        q3EditField                    matlab.ui.control.NumericEditField
        q3EditFieldLabel               matlab.ui.control.Label
        q2EditField                    matlab.ui.control.NumericEditField
        q2EditFieldLabel               matlab.ui.control.Label
        q1EditField                    matlab.ui.control.NumericEditField
        q1EditFieldLabel               matlab.ui.control.Label
        q0EditField                    matlab.ui.control.NumericEditField
        q0EditFieldLabel               matlab.ui.control.Label
        R33EditField                   matlab.ui.control.NumericEditField
        R33EditFieldLabel              matlab.ui.control.Label
        R32EditField                   matlab.ui.control.NumericEditField
        R32EditFieldLabel              matlab.ui.control.Label
        R31EditField                   matlab.ui.control.NumericEditField
        R31EditFieldLabel              matlab.ui.control.Label
        R23EditField                   matlab.ui.control.NumericEditField
        R23EditFieldLabel              matlab.ui.control.Label
        R13EditField                   matlab.ui.control.NumericEditField
        R13EditFieldLabel              matlab.ui.control.Label
        R22EditField                   matlab.ui.control.NumericEditField
        R22EditFieldLabel              matlab.ui.control.Label
        R21EditField                   matlab.ui.control.NumericEditField
        R21EditFieldLabel              matlab.ui.control.Label
        R12EditField                   matlab.ui.control.NumericEditField
        R12EditFieldLabel              matlab.ui.control.Label
        R11EditField                   matlab.ui.control.NumericEditField
        R11EditFieldLabel              matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        RollEditField                  matlab.ui.control.NumericEditField
        RollEditFieldLabel             matlab.ui.control.Label
        EndTimeEditField               matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        radLabel_3                     matlab.ui.control.Label
        radLabel_2                     matlab.ui.control.Label
        radLabel                       matlab.ui.control.Label
        YawLabel                       matlab.ui.control.Label
        RollLabel                      matlab.ui.control.Label
        PitchLabel                     matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        HeadingIndicator               Aero.ui.control.HeadingIndicator
        ArtificialHorizon              Aero.ui.control.ArtificialHorizon
        PlotDataButton                 matlab.ui.control.Button
        CayleyKleinMatrixLabel         matlab.ui.control.Label
        wZParametersLabel              matlab.ui.control.Label
        ModifiedRodriguesParametersLabel  matlab.ui.control.Label
        ClassicalRodriguesParametersLabel  matlab.ui.control.Label
        QuaternionsLabel               matlab.ui.control.Label
        VisualInterfaceLabel           matlab.ui.control.Label
        timesLabel                     matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        RotationMatrixRLabel           matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        EulerAnglesLabel               matlab.ui.control.Label
        sin001tLabel                   matlab.ui.control.Label
        sin002tLabel                   matlab.ui.control.Label
        cos001tLabel                   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NicholasBremner202019478Label  matlab.ui.control.Label
        EulerSimAppLabel               matlab.ui.control.Label
        AtttudeparametersimulationandvisualisationtoolLabel  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        RunButton                      matlab.ui.control.Button
        OrientationAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        phi
        theta
        psi
        precomputedData
    end
    
    methods (Access = private)

               function UpdateVisualization(app, currentTime)


    % Set the axes limits and remove labels and ticks
    axis(app.OrientationAxes, 'equal');
    xlim(app.OrientationAxes, [-2, 2]);
    ylim(app.OrientationAxes, [-2, 2]);
    zlim(app.OrientationAxes, [-2, 2]);

    % Remove the default axis labels
    xlabel(app.OrientationAxes, '');
    ylabel(app.OrientationAxes, '');
    zlabel(app.OrientationAxes, '');
    app.OrientationAxes.Title.Visible = 'off';
    set(app.OrientationAxes, 'XTick', [], 'YTick', [], 'ZTick', [], 'XTickLabel', [], 'YTickLabel', [], 'ZTickLabel', []);
    
    [az, el] = view(app.OrientationAxes);

    % Clear the axes
    cla(app.OrientationAxes);
        % Extract the rotation matrix at the start time
        R_start = app.precomputedData(1).R; % Assuming the first index corresponds to the start time

        % Extract the rotation matrix at the current time
        currentIndex = currentTime - app.StartTimeEditField.Value + 1;
        R_current = app.precomputedData(currentIndex).R;

        % Define unit vectors for the reference frame
        ref_length = 1.5;
        x_axis_ref = ref_length  *R_start * [1; 0; 0];
        y_axis_ref = ref_length * R_start * [0; 1; 0];
        z_axis_ref = ref_length * R_start * [0; 0; 1];

        % Plot the reference frame (stationary, solid lines)
        labelOffset = 0.2;

        hold(app.OrientationAxes, 'on');
        quiver3(app.OrientationAxes, 0, 0, 0, x_axis_ref(1), x_axis_ref(2), x_axis_ref(3), 'r', 'LineWidth', 1, 'AutoScale', 'off');
        text(app.OrientationAxes, x_axis_ref(1) * (1 + labelOffset), x_axis_ref(2) * (1 + labelOffset), x_axis_ref(3) * (1 + labelOffset), 'x', 'FontSize', 15, 'Color', 'r');

        quiver3(app.OrientationAxes, 0, 0, 0, y_axis_ref(1), y_axis_ref(2), y_axis_ref(3), 'g', 'LineWidth', 1, 'AutoScale', 'off');
        text(app.OrientationAxes, y_axis_ref(1) * (1 + labelOffset), y_axis_ref(2) * (1 + labelOffset), y_axis_ref(3) * (1 + labelOffset), 'y', 'FontSize', 15, 'Color', 'g');

        quiver3(app.OrientationAxes, 0, 0, 0, z_axis_ref(1), z_axis_ref(2), z_axis_ref(3), 'b', 'LineWidth', 1, 'AutoScale', 'off');
        text(app.OrientationAxes, z_axis_ref(1) * (1 + labelOffset), z_axis_ref(2) * (1 + labelOffset), z_axis_ref(3) * (1 + labelOffset), 'z', 'FontSize', 15, 'Color', 'b');

        % Define unit vectors for the rotating frame (dashed lines)
        rot_length = 1.0;
        x_axis_rot = rot_length * R_current * [1; 0; 0];
        y_axis_rot = rot_length * R_current * [0; 1; 0];
        z_axis_rot = rot_length * R_current * [0; 0; 1];

        % Plot the rotating frame (Euler angles, dashed lines)
        quiver3(app.OrientationAxes, 0, 0, 0, x_axis_rot(1), x_axis_rot(2), x_axis_rot(3), 'r--', 'LineWidth', 1, 'AutoScale', 'off');
        text(app.OrientationAxes, x_axis_rot(1) * (1 + labelOffset), x_axis_rot(2) * (1 + labelOffset), x_axis_rot(3) * (1 + labelOffset), 'x''', 'FontSize', 15, 'Color', 'r');
    
        quiver3(app.OrientationAxes, 0, 0, 0, y_axis_rot(1), y_axis_rot(2), y_axis_rot(3), 'g--', 'LineWidth', 1, 'AutoScale', 'off'); 
        text(app.OrientationAxes, y_axis_rot(1) * (1 + labelOffset), y_axis_rot(2) * (1 + labelOffset), y_axis_rot(3) * (1 + labelOffset), 'y''', 'FontSize', 15, 'Color', 'g');

        quiver3(app.OrientationAxes, 0, 0, 0, z_axis_rot(1), z_axis_rot(2), z_axis_rot(3), 'b--', 'LineWidth', 1, 'AutoScale', 'off');
        text(app.OrientationAxes, z_axis_rot(1) * (1 + labelOffset), z_axis_rot(2) * (1 + labelOffset), z_axis_rot(3) * (1 + labelOffset), 'z''', 'FontSize', 15, 'Color', 'b');

        % Create legend
        legend(app.OrientationAxes, {'x (start time)', 'y (start time)', 'z (start time)', 'x'' (current time)', 'y'' (current time)', 'z'' (current time)'}, 'Location', 'best');

        view(app.OrientationAxes, az, el);

        hold(app.OrientationAxes, 'off'); 
    
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
% Prompt the user for time range
t_start = app.StartTimeEditField.Value;
t_end = app.EndTimeEditField.Value;

%Slider
app.Slider.Limits = [t_start, t_end];
    app.Slider.Value = t_start;

    % Make the slider visible
    app.Slider.Visible = 'on';

    view(app.OrientationAxes, 3);

     % Adjust slider scale
     range = t_end - t_start;
    if range / 200 >= 1
        majorTickInterval = 50;
    elseif range / 100 >= 1
        majorTickInterval = 25;
    elseif range / 10 >= 1
        majorTickInterval = 10;
    elseif range / 5 >= 1
        majorTickInterval = 5;
    elseif range / 2 >= 1
        majorTickInterval = 2;
    else
        majorTickInterval = 1;  % Fallback to 1 if range is too small
    end

    % Ensure ticks start and end at multiples of the interval
    adjustedStart = ceil(t_start / majorTickInterval) * majorTickInterval;
    adjustedEnd = floor(t_end / majorTickInterval) * majorTickInterval;

    % Set the major ticks based on the chosen interval
    app.Slider.MajorTicks = adjustedStart:majorTickInterval:adjustedEnd;

    % Include the start and end limits
    if ~ismember(t_start, app.Slider.MajorTicks)
        app.Slider.MajorTicks = [t_start, app.Slider.MajorTicks];
    end
    if ~ismember(t_end, app.Slider.MajorTicks)
        app.Slider.MajorTicks = [app.Slider.MajorTicks, t_end];
    end

% Define the time vector from start to end time with appropriate time step
t= t_start:1:t_end;  

  app.phi = 20 * sin(0.01 * t);
  app.theta = 30 * sin(0.02 * t);
  app.psi = 10 * cos(0.01 * t);

  phideg= 20 * sind(0.01 * t_start);
  thetadeg = 30 * sind(0.02 * t_start);
  psideg = 10 * cosd(0.01 * t_start);
  
  app.precomputedData = repmat(struct('R', zeros(3), 'quaternion', zeros(1, 4), 'CRP', zeros(1, 4), 'MRP', zeros(1, 4), 'CK', cell(2), 'wz', zeros(1, 3), 'zetaMRP_scalar', 0, 'zetaCRP_scalar', 0, 'phirad', 0,  'thetarad', 0, 'psirad', 0, 'phiheading', 0,  'thetaheading', 0, 'psiheading', 0), length(t), 1);

% Preallocate array for quaternions
quaternions = zeros(length(t), 4);

% Initialize last quaternion to ensure continuity
q_last = [1, 0, 0, 0];

% Calculate the Euler angles and compute the quaternion at each time step
for index = 1:length(t)

    %Euler angles
    phirad = app.phi(index) ;  
    thetarad = app.theta(index); 
    psirad = app.psi(index);   

    app.precomputedData(index).phirad = phirad;   
    app.precomputedData(index).thetarad = thetarad; 
    app.precomputedData(index).psirad = psirad;

    phiheading= 20 * sind(0.01 * index);
    thetaheading= 30 * sind(0.02 * index);
    psiheading = 10 * cosd(0.01 * index);

    app.precomputedData(index).phiheading = phiheading;   
    app.precomputedData(index).thetaheading = thetaheading; 
    app.precomputedData(index).psiheading= psiheading;

    % Compute the rotation matrix R
    R = [cos(thetarad)*cos(psirad), -cos(phirad)*sin(psirad) + sin(phirad)*sin(thetarad)*cos(psirad), sin(phirad)*sin(psirad) + cos(phirad)*sin(thetarad)*cos(psirad);
         cos(thetarad)*sin(psirad), cos(phirad)*cos(psirad) + sin(phirad)*sin(thetarad)*sin(psirad), -sin(phirad)*cos(psirad) + cos(phirad)*sin(thetarad)*sin(psirad);
         -sin(thetarad), sin(phirad)*cos(thetarad), cos(phirad)*cos(thetarad)];

    app.precomputedData(index).R = R;

    %Quaternion
    tr = trace(R);

    % Preallocate the quaternion
    q = zeros(1, 4);

    % Check the trace to determine the largest component of the quaternion
    if tr > 0
        S = sqrt(tr + 1.0) * 2; % S=4*q0 
        q0 = 0.25 * S;
        q1 = (R(3,2) - R(2,3)) / S;
        q2 = (R(1,3) - R(3,1)) / S;
        q3 = (R(2,1) - R(1,2)) / S;
    elseif (R(1,1) > R(2,2)) && (R(1,1) > R(3,3))
        S = sqrt(1.0 + R(1,1) - R(2,2) - R(3,3)) * 2; % S=4*q1
        q0 = (R(3,2) - R(2,3)) / S;
        q1 = 0.25 * S;
        q2 = (R(1,2) + R(2,1)) / S;
        q3 = (R(1,3) + R(3,1)) / S;
    elseif R(2,2) > R(3,3)
        S = sqrt(1.0 + R(2,2) - R(1,1) - R(3,3)) * 2; % S=4*q2
        q0 = (R(1,3) - R(3,1)) / S;
        q1 = (R(1,2) + R(2,1)) / S;
        q2 = 0.25 * S;
        q3 = (R(2,3) + R(3,2)) / S;
    else
        S = sqrt(1.0 + R(3,3) - R(1,1) - R(2,2)) * 2; % S=4*q3
        q0 = (R(2,1) - R(1,2)) / S;
        q1 = (R(1,3) + R(3,1)) / S;
        q2 = (R(2,3) + R(3,2)) / S;
        q3 = 0.25 * S;
    end

    % Normalize the quaternion to ensure the shortest path
    norm_q = sqrt(q0^2 + q1^2 + q2^2 + q3^2);
    q = [q0, q1, q2, q3] / norm_q;

    % Ensure continuity by checking the dot product
    if dot(q, q_last) < 0
        q = -q;
    end

    % Store the quaternion
    quaternions(index, :) = q;

    % Update the last quaternion
    q_last = q;

    app.precomputedData(index).quaternion = q;

    % CRP computation
    g0 = sqrt(trace(R) + 1); % Obtaining Rodrigues scalar (equation 49)

    g1 = (1/(g0^2)) * (R(2,3) - R(3,2)); % Obtaining classical Rodrigues vector values relating to equation 51
    g2 = (1/(g0^2)) * (R(3,1) - R(1,3)); 
    g3 = (1/(g0^2)) * (R(1,2) - R(2,1)); 

    app.precomputedData(index).CRP = [g1 g2 g3 g0];

    % MRP computation
    s1 = q1 / (1 + q0);
    s2 = q2 / (1 + q0);
    s3 = q3 / (1 + q0);
    s0 = q0/2;
    app.precomputedData(index).MRP = [s1 s2 s3 s0];

    % Cayley-Klein parameters computation
    alpha = exp((1i * (psirad + phirad)) / 2) * cos(thetarad / 2);
    beta = 1i * exp((1i * (psirad - phirad)) / 2) * sin(thetarad / 2);
    gamma = 1i * exp((-1i * (psirad - phirad)) / 2) * sin(thetarad / 2);
    delta = exp((-1i * (psirad + phirad)) / 2) * cos(thetarad / 2);
    app.precomputedData(index).CK = {alpha, beta; gamma, delta};

    % w, z parameters computation
    t1 = (q2 * q3 + q0 * q1) / (q0^2 + q3^2);
    t2 = (q0 * q2 - q1 * q3) / (q0^2 + q3^2);
    t3 = 2 * atan(q3 / q0);
    app.precomputedData(index).wz = [t1 t2 t3];

    %Updating visuals
    UpdateVisualization(app, t_start);
    app.HeadingIndicator.Heading = psideg; 
    app.ArtificialHorizon.Roll = phideg;   
    app.ArtificialHorizon.Pitch = thetadeg; 

end

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
          
            changingValue = event.Value;
            index = round(changingValue - app.Slider.Limits(1)) + 1;
             
    % Get the current slider value
    currentTime = round(event.Value);

    % Update the current time display
    app.CurrentTimeEditField.Value = currentTime;

    % Fetch precomputed data for the current index
        precomputed = app.precomputedData(index);

        % Update the UI with precomputed data

        app.RollEditField.Value = precomputed.phirad;
        app.PitchEditField.Value = precomputed.thetarad;
        app.YawEditField.Value = precomputed.psirad;
        
        app.R11EditField.Value = precomputed.R(1,1);
        app.R12EditField.Value = precomputed.R(1,2);
        app.R13EditField.Value = precomputed.R(1,3);
        app.R21EditField.Value = precomputed.R(2,1);
        app.R22EditField.Value = precomputed.R(2,2);
        app.R23EditField.Value = precomputed.R(2,3);
        app.R31EditField.Value = precomputed.R(3,1);
        app.R32EditField.Value = precomputed.R(3,2);
        app.R33EditField.Value = precomputed.R(3,3);

        % Update quaternion edit fields
        app.q0EditField.Value = precomputed.quaternion(1);
        app.q1EditField.Value = precomputed.quaternion(2);
        app.q2EditField.Value = precomputed.quaternion(3);
        app.q3EditField.Value = precomputed.quaternion(4);

        % Update CRP edit fields
        app.g1EditField.Value = precomputed.CRP(1);
        app.g2EditField.Value = precomputed.CRP(2);
        app.g3EditField.Value = precomputed.CRP(3);

        % Update MRP edit fields
        app.s1EditField.Value = precomputed.MRP(1);
        app.s2EditField.Value = precomputed.MRP(2);
        app.s3EditField.Value = precomputed.MRP(3);

        % Update Cayley-Klein matrix field
        app.CK11TextArea.Value = num2str(precomputed.CK{1,1});
        app.CK12TextArea.Value = num2str(precomputed.CK{1,2});
        app.CK21TextArea.Value = num2str(precomputed.CK{2,1});
        app.CK22TextArea.Value = num2str(precomputed.CK{2,2});

        % Update w, z edit fields
        app.t1EditField.Value = precomputed.wz(1);
        app.t2EditField.Value = precomputed.wz(2);
        app.t3EditField.Value = precomputed.wz(3);

        % update visuals
        UpdateVisualization(app, currentTime);
        app.HeadingIndicator.Heading = precomputed.psiheading;
        app.ArtificialHorizon.Roll = precomputed.phiheading;
        app.ArtificialHorizon.Pitch = precomputed.thetaheading;
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            % Initialise the time vector
    t_start = app.StartTimeEditField.Value;
    t_end = app.EndTimeEditField.Value;
    t = t_start:1:t_end;

    % Initialise arrays
    

    phideg = 20 * sind(0.01 * t); % Phi angle as a function of time
    thetadeg = 30 * sind(0.02 * t); % Theta angle as a function of time
    psideg = 10 * cosd(0.01 * t); % Psi angle as a function of time

    q0 = zeros(1, length(t));
    q1 = zeros(1, length(t));
    q2 = zeros(1, length(t));
    q3 = zeros(1, length(t));

    CRP1 = zeros(1, length(t));
    CRP2 = zeros(1, length(t));
    CRP3 = zeros(1, length(t));
    CRP0 = zeros(1, length(t));

    MRP1 = zeros(1, length(t));
    MRP2 = zeros(1, length(t));
    MRP3 = zeros(1, length(t));
    MRP0 = zeros(1, length(t));

    w1 = zeros(1, length(t));
    w2 = zeros(1, length(t));
    Z = zeros(1, length(t));
   
    alpha_real = zeros(1, length(t));
    alpha_imag = zeros(1, length(t));
    beta_real = zeros(1, length(t));
    beta_imag = zeros(1, length(t));
    gamma_real = zeros(1, length(t));
    gamma_imag = zeros(1, length(t));
    delta_real = zeros(1, length(t));
    delta_imag = zeros(1, length(t));

    % Extract data for each time step
    for i = 1:length(t)

        q0(i) = app.precomputedData(i).quaternion(1);
        q1(i) = app.precomputedData(i).quaternion(2);
        q2(i) = app.precomputedData(i).quaternion(3);
        q3(i) = app.precomputedData(i).quaternion(4);

        g1 = q1(i) / q0(i);
        g2 = q2(i) / q0(i);
        g3 = q3(i) / q0(i);
        g0 = 2*q0(i);
        app.precomputedData(i).CRP = [g1 g2 g3 g0];

        CRP1(i) = g1;
        CRP2(i) = g2;
        CRP3(i) = g3;
        CRP0(i) = g0;

        s1 = q1(i) / (1 + q0(i));
        s2 = q2(i) / (1 + q0(i));
        s3 = q3(i) / (1 + q0(i));
        s0 = q0(i)/2;
        app.precomputedData(i).MRP = [s1 s2 s3 s0];

        MRP1(i) = s1;
        MRP2(i) = s2;
        MRP3(i) = s3;
        MRP0(i) = s0;

        CK = app.precomputedData(i).CK;
        alpha = CK{1,1};
        beta = CK{1,2};
        gamma = CK{2,1};
        delta = CK{2,2};

        alpha_real(i) = real(alpha);
        alpha_imag(i) = imag(alpha);
        beta_real(i) = real(beta);
        beta_imag(i) = imag(beta);
        gamma_real(i) = real(gamma);
        gamma_imag(i) = imag(gamma);
        delta_real(i) = real(delta);
        delta_imag(i) = imag(delta);


        t1 = (q2(i) * q3(i) + q0(i) * q1(i)) / (q0(i)^2 + q3(i)^2);
        t2 = (q0(i) * q2(i) - q1(i) * q3(i)) / (q0(i)^2 + q3(i)^2);
        t3 = 2 * atan(q3(i) / q0(i));
        app.precomputedData(i).wz = [t1 t2 t3];
    
        w1(i) = t1;
        w2(i) = t2;
        Z(i) = t3;
       

    end

    % Plotting the Euler angles
    figure('Name', 'Euler Angles')
    plot(t, psideg, 'r-', t, thetadeg, 'g-', t, phideg, 'b-');
    title('Euler Angles vs Time');
    xlabel('time (s)');
    ylabel('Euler angle value (degrees)');
    legend('ψ', 'θ', 'φ');
    grid on;

    % Plotting the quaternion components
    figure('Name', 'Quaternions')
    plot(t, q0, 'r-', t, q1, 'g-', t, q2, 'b-', t, q3, 'k-');
    title('Quaternion Components vs Time');
    xlabel('time (s)');
    ylabel('quaternion value');
    legend('η', 'ε₁', 'ε₂', 'ε₃');
    grid on;

     % Plotting the CRP components
    figure('Name', 'Classical Rodrigues Parameters'); 
    plot(t, CRP1, 'r-', t, CRP2, 'g-', t, CRP3, 'b-', t, CRP0, 'k-');
    title('CRP Components vs. Time');
    xlabel('time (s)');
    ylabel('CRP value');
    legend('ρ₁', 'ρ₂', 'ρ₃', 'ζ');
    grid on;

      % Plotting the MRP components
    figure('Name', 'Modified Rodrigues Parameters'); 
    plot(t, MRP1, 'r-', t, MRP2, 'g-', t, MRP3, 'b-', t, MRP0, 'k-');
    title('MRP Components vs. Time');
    xlabel('time (s)');
    ylabel('MRP value');
    legend('σ₁ ', 'σ₂', 'σ₃', 'ζ');
    grid on;

     % Plotting the CK components
    figure('Name', 'Cayley-Klein Parameters');
    hold on;
    plot3(alpha_real, t, alpha_imag, 'r-');
    plot3(beta_real, t, beta_imag, 'g-');
    plot3(gamma_real, t, gamma_imag, 'b-');
    plot3(delta_real, t, delta_imag, 'k-');
    hold off;
    title('Cayley-Klein Components vs Time');
    xlabel('real part');
    ylabel('time (s)');
    zlabel('imaginary part');
    legend('𝛼', '𝛽', '𝛾 ', '𝛿 ');
    grid on;
    set(gca, 'YDir','reverse');
    view(-135, 30);

     % Plotting the w z parameters
    figure('Name', 'w, Z Parameters')
    plot(t, w1, 'r-', t, w2, 'g-', t, Z, 'b-');
    title('(w, Z Parameters vs Time');
    xlabel('time (s)');
    ylabel('w, Z Parameter');
    legend('w_1', 'w_2', 'Z');
    grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrientationAxes = uiaxes(app.UIFigure);
            app.OrientationAxes.XTick = [];
            app.OrientationAxes.XTickLabel = '';
            app.OrientationAxes.YTick = [];
            app.OrientationAxes.ZTick = [];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Visible = 'off';
            app.AtttudeparametersimulationandvisualisationtoolLabel = uilabel(app.UIFigure);
            app.AtttudeparametersimulationandvisualisationtoolLabel.Text = 'Atttude parameter simulation tool for time-dependent Euler status:';
            app.EulerSimAppLabel = uilabel(app.UIFigure);
            app.EulerSimAppLabel.Text = 'EulerSimApp';
            app.NicholasBremner202019478Label = uilabel(app.UIFigure);
            app.NicholasBremner202019478Label.Text = 'Nicholas Bremner 202019478';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.cos001tLabel = uilabel(app.UIFigure);
            app.cos001tLabel.Text = 'ψ  = 10cos(0.01t)';
            app.sin002tLabel = uilabel(app.UIFigure);
            app.sin002tLabel.Text = 'θ = 30sin(0.02t)';
            app.sin001tLabel = uilabel(app.UIFigure);
            app.sin001tLabel.Text = 'ϕ = 20sin(0.01t)';
            app.EulerAnglesLabel = uilabel(app.UIFigure);
            app.EulerAnglesLabel.Text = 'Euler Angles';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Please enter a valid time range in the right hand panel and press run. App assumes 3-2-1 sequence (ZYX). Graphical and simulation outputs will update dynamically as slider is operated by user. Updates occur with an index of 1 second. Plot data button displays graphs of relevant parameters against the desired time range. Artificial horizon and heading gauge is updated according to current Euler angles in degrees.'};
            app.RotationMatrixRLabel = uilabel(app.UIFigure);
            app.RotationMatrixRLabel.Text = 'Rotation Matrix R';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '(s)';
            app.timesLabel = uilabel(app.UIFigure);
            app.timesLabel.Text = 'time (s)';
            app.VisualInterfaceLabel = uilabel(app.UIFigure);
            app.VisualInterfaceLabel.Text = 'Visual Interface';
            app.QuaternionsLabel = uilabel(app.UIFigure);
            app.QuaternionsLabel.Text = 'Quaternions';
            app.ClassicalRodriguesParametersLabel = uilabel(app.UIFigure);
            app.ClassicalRodriguesParametersLabel.Text = 'Classical Rodrigues Parameters ';
            app.ModifiedRodriguesParametersLabel = uilabel(app.UIFigure);
            app.ModifiedRodriguesParametersLabel.Text = 'Modified Rodrigues Parameters ';
            app.wZParametersLabel = uilabel(app.UIFigure);
            app.wZParametersLabel.Text = '(w, Z) Parameters';
            app.CayleyKleinMatrixLabel = uilabel(app.UIFigure);
            app.CayleyKleinMatrixLabel.Text = 'Cayley-Klein Matrix';
            app.PlotDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.ArtificialHorizon = uiaerohorizon(app.UIFigure);
            app.HeadingIndicator = uiaeroheading(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '↺';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '↺';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '↕';
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch (θ)';
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll (ϕ)';
            app.YawLabel = uilabel(app.UIFigure);
            app.YawLabel.Text = 'Yaw (ψ)';
            app.radLabel = uilabel(app.UIFigure);
            app.radLabel.Text = 'rad';
            app.radLabel_2 = uilabel(app.UIFigure);
            app.radLabel_2.Text = 'rad';
            app.radLabel_3 = uilabel(app.UIFigure);
            app.radLabel_3.Text = 'rad';
            app.StartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeEditFieldLabel.Text = 'Start Time:';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimeEditFieldLabel.Text = 'End Time:';
            app.EndTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll (ϕ)';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch (θ)';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw (ψ)';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.Editable = 'off';
            app.R11EditFieldLabel = uilabel(app.UIFigure);
            app.R11EditFieldLabel.Text = 'R11';
            app.R11EditField = uieditfield(app.UIFigure, 'numeric');
            app.R11EditField.Editable = 'off';
            app.R12EditFieldLabel = uilabel(app.UIFigure);
            app.R12EditFieldLabel.Text = 'R12';
            app.R12EditField = uieditfield(app.UIFigure, 'numeric');
            app.R12EditField.Editable = 'off';
            app.R21EditFieldLabel = uilabel(app.UIFigure);
            app.R21EditFieldLabel.Text = 'R21';
            app.R21EditField = uieditfield(app.UIFigure, 'numeric');
            app.R21EditField.Editable = 'off';
            app.R22EditFieldLabel = uilabel(app.UIFigure);
            app.R22EditFieldLabel.Text = 'R22';
            app.R22EditField = uieditfield(app.UIFigure, 'numeric');
            app.R22EditField.Editable = 'off';
            app.R13EditFieldLabel = uilabel(app.UIFigure);
            app.R13EditFieldLabel.Text = 'R13';
            app.R13EditField = uieditfield(app.UIFigure, 'numeric');
            app.R13EditField.Editable = 'off';
            app.R23EditFieldLabel = uilabel(app.UIFigure);
            app.R23EditFieldLabel.Text = 'R23';
            app.R23EditField = uieditfield(app.UIFigure, 'numeric');
            app.R23EditField.Editable = 'off';
            app.R31EditFieldLabel = uilabel(app.UIFigure);
            app.R31EditFieldLabel.Text = 'R31';
            app.R31EditField = uieditfield(app.UIFigure, 'numeric');
            app.R31EditField.Editable = 'off';
            app.R32EditFieldLabel = uilabel(app.UIFigure);
            app.R32EditFieldLabel.Text = 'R32';
            app.R32EditField = uieditfield(app.UIFigure, 'numeric');
            app.R32EditField.Editable = 'off';
            app.R33EditFieldLabel = uilabel(app.UIFigure);
            app.R33EditFieldLabel.Text = 'R33';
            app.R33EditField = uieditfield(app.UIFigure, 'numeric');
            app.R33EditField.Editable = 'off';
            app.q0EditFieldLabel = uilabel(app.UIFigure);
            app.q0EditFieldLabel.Text = 'η (q0)';
            app.q0EditField = uieditfield(app.UIFigure, 'numeric');
            app.q0EditField.Editable = 'off';
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'ε₁ (q1)';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditField.Editable = 'off';
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'ε₂ (q2)';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditField.Editable = 'off';
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'ε₃ (q3)';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditField.Editable = 'off';
            app.g1EditFieldLabel = uilabel(app.UIFigure);
            app.g1EditFieldLabel.Text = 'ρ₁ (g1)';
            app.g1EditField = uieditfield(app.UIFigure, 'numeric');
            app.g1EditField.Editable = 'off';
            app.g2EditFieldLabel = uilabel(app.UIFigure);
            app.g2EditFieldLabel.Text = 'ρ₂ (g2)';
            app.g2EditField = uieditfield(app.UIFigure, 'numeric');
            app.g2EditField.Editable = 'off';
            app.g3EditFieldLabel = uilabel(app.UIFigure);
            app.g3EditFieldLabel.Text = 'ρ₃ (g3)';
            app.g3EditField = uieditfield(app.UIFigure, 'numeric');
            app.g3EditField.Editable = 'off';
            app.s1EditFieldLabel = uilabel(app.UIFigure);
            app.s1EditFieldLabel.Text = 'σ₁ (s1)';
            app.s1EditField = uieditfield(app.UIFigure, 'numeric');
            app.s1EditField.Editable = 'off';
            app.s2EditFieldLabel = uilabel(app.UIFigure);
            app.s2EditFieldLabel.Text = 'σ₂ (s2)';
            app.s2EditField = uieditfield(app.UIFigure, 'numeric');
            app.s2EditField.Editable = 'off';
            app.s3EditFieldLabel = uilabel(app.UIFigure);
            app.s3EditFieldLabel.Text = 'σ₃ (s3)';
            app.s3EditField = uieditfield(app.UIFigure, 'numeric');
            app.s3EditField.Editable = 'off';
            app.CK11TextAreaLabel = uilabel(app.UIFigure);
            app.CK11TextAreaLabel.Text = '𝛼 (CK11)';
            app.CK11TextArea = uitextarea(app.UIFigure);
            app.CK11TextArea.Editable = 'off';
            app.CK12TextAreaLabel = uilabel(app.UIFigure);
            app.CK12TextAreaLabel.Text = '𝛽 (CK12)';
            app.CK12TextArea = uitextarea(app.UIFigure);
            app.CK12TextArea.Editable = 'off';
            app.CK21TextAreaLabel = uilabel(app.UIFigure);
            app.CK21TextAreaLabel.Text = '𝛾 (CK21)';
            app.CK21TextArea = uitextarea(app.UIFigure);
            app.CK21TextArea.Editable = 'off';
            app.CK22TextAreaLabel = uilabel(app.UIFigure);
            app.CK22TextAreaLabel.Text = '𝛿 (CK22)';
            app.CK22TextArea = uitextarea(app.UIFigure);
            app.CK22TextArea.Editable = 'off';
            app.CurrentTimeEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentTimeEditFieldLabel.Text = 'Current Time';
            app.CurrentTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentTimeEditField.Editable = 'off';
            app.wLabel = uilabel(app.UIFigure);
            app.wLabel.Text = 'w₁ (t1) ';
            app.t1EditField = uieditfield(app.UIFigure, 'numeric');
            app.t1EditField.Editable = 'off';
            app.wt2Label = uilabel(app.UIFigure);
            app.wt2Label.Text = 'w₂ (t2) ';
            app.t2EditField = uieditfield(app.UIFigure, 'numeric');
            app.t2EditField.Editable = 'off';
            app.t2Label = uilabel(app.UIFigure);
            app.t2Label.Text = ' z (t3) ';
            app.t3EditField = uieditfield(app.UIFigure, 'numeric');
            app.t3EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GROUND

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end